classdef smoothing_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        AutomaticButton            matlab.ui.control.Button
        StateDropDown              matlab.ui.control.DropDown
        StateDropDownLabel         matlab.ui.control.Label
        CellsSlider                matlab.ui.control.Slider
        CellstodisplaySliderLabel  matlab.ui.control.Label
        MultiplierSlider           matlab.ui.control.Slider
        logLabel                   matlab.ui.control.Label
        FinishButton               matlab.ui.control.Button
        KnotsSlider                matlab.ui.control.Slider
        OverridewithequidistantknotsSliderLabel  matlab.ui.control.Label
        LiveCheckBox               matlab.ui.control.CheckBox
        SmoothButton               matlab.ui.control.Button
        PenalizedIntervalUIAxes    matlab.ui.control.UIAxes
        KnotsSelectorUIAxes        matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        smoother
        state_names
        tl
        lastplots
        
        state_lambda
        
        knots
    end
    
    methods (Access = private)
        function smooth(app)
            rng('default')
            
            app.smoother.settings.order = app.OrderSlider.Value;
            app.smoother.settings.knots = [0 app.knots 1];
            app.smoother.settings.linear = app.smoother.data.t(end) * [app.LinearSlider.Value 1];
            if app.MultiplierSlider.Enable
                app.smoother.lambda = 10^app.MultiplierSlider.Value * ones(1, length(app.smoother.lambda));
            else
                app.smoother.lambda = app.state_lambda;
            end
            
            app.smoother.bspline = BSpline(app.smoother.settings.order, app.smoother.settings.knots);
            app.smoother.optimize()
        end
        
        function plot_smoothed(app)
            for idx = 1:numel(app.lastplots), delete(app.lastplots(idx)), end
            
            observed_state_index = find(strcmp(app.StateDropDown.Value, app.state_names));
            
            if string(app.StateDropDown.Value) ~= "All"
                n_cells = app.CellsSlider.Value;
                ax = nexttile(app.tl, idx);
                app.lastplots(1:n_cells, 1) = plot(ax, linspace(app.smoother.data.t(1), app.smoother.data.t(end), 201), ...
                                                   reshape(app.smoother.data.smoothed_fine(:, observed_state_index, 1:n_cells), ...
                                                           length(app.smoother.fine), []), ...
                                                   '--', 'LineWidth', 1.25);
                set(app.lastplots(1:n_cells, 1), {'color'}, num2cell(turbo(n_cells), 2));
                title(ax, app.StateDropDown.Value)
                xlabel(ax, 'time (min)')
                ylabel(ax, 'value (AU)')
            else
                n_cells = app.CellsSlider.Value;
                observed_indices = find(app.active);
                for idx = 1:length(observed_indices)
                    ax = nexttile(app.tl, idx);
                    app.lastplots(1:n_cells, idx) = plot(ax, linspace(app.smoother.data.t(1), app.smoother.data.t(end), 201), ...
                                                         reshape(app.smoother.data.smoothed_fine(:, idx, 1:n_cells), ...
                                                                 length(app.smoother.fine), []), ...
                                                         '--', 'LineWidth', 1.25);
                    set(app.lastplots(1:n_cells, idx), {'color'}, num2cell(turbo(n_cells), 2));
                    title(ax, app.smoother.system.states(app.smoother.data.observed(observed_indices(idx))))
                    xlabel(ax, 'time (min)')
                    ylabel(ax, 'value (AU)')
                end
            end
        end
        
        function setup_plots(app)
            if string(app.StateDropDown.Value) ~= "All"
                observed_state_index = find(strcmp(app.StateDropDown.Value, app.state_names));
                delete(app.tl)
                app.tl = tiledlayout(app.RightPanel, 1, 1);
                
                ax = nexttile(app.tl);
                hold(ax, 'on')
                h = plot(ax, app.smoother.data.t, ...
                         reshape(app.smoother.data.traces(:, observed_state_index, 1:app.CellsSlider.Value), ...
                                 length(app.smoother.data.t), []));
                set(h, {'color'}, num2cell(turbo(app.CellsSlider.Value), 2));
                title(ax, app.StateDropDown.Value)
                xlabel(ax, 'time (min)')
                ylabel(ax, 'value (AU)')
            else
                nplots = length(app.smoother.data.observed);
                ncols = ceil(sqrt(nplots));
                nrows = ceil(nplots / ncols);
                delete(app.tl)
                app.tl = tiledlayout(app.RightPanel, nrows, ncols);
                
                for idx = 1:nplots
                    ax = nexttile(app.tl, idx);
                    hold(ax, 'on')
                    h = plot(ax, app.smoother.data.t, ...
                             reshape(app.smoother.data.traces(:, idx, 1:app.CellsSlider.Value), ...
                                     length(app.smoother.data.t), []));
                    set(h, {'color'}, num2cell(turbo(app.CellsSlider.Value), 2));
                    title(ax, app.state_names{idx})
                    xlabel(ax, 'time (min)')
                    ylabel(ax, 'value (AU)')
                end
            end
        end
        
        function update_knots(app)
            app.smooth();
            
            t = app.smoother.fine * range(app.smoother.data.t) + app.smoother.data.t(1);
            basis = app.smoother.X_fine;
            
            plot(app.KnotsSelectorUIAxes, t, basis, 'HitTest', 'off')
            
            if ~isempty(app.knots)
                hold(app.KnotsSelectorUIAxes, 'on')
                xline(app.KnotsSelectorUIAxes, app.knots * range(app.smoother.data.t) + app.smoother.data.t(1), 'k--', 'HitTest', 'off')
                hold(app.KnotsSelectorUIAxes, 'off')
            end
            
            xlim(app.KnotsSelectorUIAxes, [min(app.smoother.data.t) max(app.smoother.data.t)])
            ylim(app.KnotsSelectorUIAxes, [0 1])
            
            if app.LiveCheckBox.Value
                app.plot_smoothed()
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, smoother)
            app.smoother = smoother;
            
            app.state_names = convertStringsToChars(smoother.system.states(smoother.data.observed));
            if length(smoother.data.observed) == 1
                app.state_names = {app.state_names};
            end
            app.StateDropDown.Items = ['All'; app.state_names];
            app.StateDropDown.Value = 'All';
            
            N = smoother.data.N;
            nobs = length(smoother.data.observed);
            app.lastplots = gobjects(N, nobs);
            
            app.knots = cell(1, nobs);
            for idx = 1:nobs
                app.knots{idx} = smoother.settings.knots{idx}(~ismember(smoother.settings.knots{idx}, [0 1]));
            end
            app.KnotsSlider.Value = length(app.knots{1}); %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% FIX
            
            app.CellsSlider.Limits = [1 smoother.data.N];
            app.CellsSlider.Value = min(20, N);
            
            steps = [1 2 5 10 25 50 100 250 500 1000 2500 5000 10000];
            [~, minIdx] = min(abs(N/5 - steps));
            majorsize = steps(minIdx);
            minorsize = ceil(majorsize/5);
            app.CellsSlider.MajorTicksMode = 'manual';
            app.CellsSlider.MajorTicks = [1 majorsize : majorsize : N-mod(N,ceil(majorsize/2))-ceil(majorsize/2) N];
            app.CellsSlider.MinorTicksMode = 'manual';
            app.CellsSlider.MinorTicks = [1 minorsize : minorsize : N-minorsize];
            
            app.SmoothButton.Enable = false;
            
            app.setup_plots()
            app.update_knots()
        end

        % Value changed function: StateDropDown
        function StateValueChanged(app, event)
            app.setup_plots()
%             if app.LiveCheckBox.Value
%                 app.smooth()
%                 app.plot_smoothed()
%             end
        end

        % Value changed function: MultiplierSlider
        function MultiplierValueChanged(app, event)
            value = app.MultiplierSlider.Value;
            if abs(value) < .25
                event.Source.Value = 0;
            end
            if app.LiveCheckBox.Value
                app.smooth()
                app.plot_smoothed()
            end
        end

        % Button down function: PenalizedIntervalUIAxes
        function PenalizedIntervalButtonDown(app, event)
            
        end

        % Button pushed function: AutomaticButton
        function AutomaticButtonPushed(app, event)
            
        end

        % Value changed function: KnotsSlider
        function KnotsSliderValueChanged(app, event)
            value = app.KnotsSlider.Value;
            event.Source.Value = round(value);
            
            app.knots = linspace(0, 1, app.KnotsSlider.Value+2);
            app.knots = app.knots(2:end-1);
            
            app.update_knots()
        end

        % Button down function: KnotsSelectorUIAxes
        function KnotsSelectorButtonDown(app, event)
            x = app.KnotsSelectorUIAxes.CurrentPoint(1,1);
            
            knots_rescaled = app.knots * range(app.smoother.data.t) + app.smoother.data.t(1);
            x_normalized = (x - app.smoother.data.t(1)) / range(app.smoother.data.t);
            
            if strcmp(app.UIFigure.SelectionType, 'alt')
                if length(app.knots) > 1
                    distances = abs(x - knots_rescaled);
                    app.knots = app.knots(distances > min(distances));
                else
                    app.knots = [];
                end
            else
                app.knots = [app.knots x_normalized];
            end
            
            app.knots = sort(app.knots);
            
            app.update_knots()
        end

        % Value changed function: CellsSlider
        function CellsSliderValueChanged(app, event)
            event.Source.Value = round(app.CellsSlider.Value);
            app.setup_plots()
            app.plot_smoothed()
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            app.smooth()
            app.plot_smoothed()
        end

        % Value changed function: LiveCheckBox
        function LiveValueChanged(app, event)
            app.SmoothButton.Enable = ~app.LiveCheckBox.Value;
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.FinishButton.UserData = 1;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {900, 900};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.KnotsSelectorUIAxes = uiaxes(app.LeftPanel);
            title(app.KnotsSelectorUIAxes, 'Manual knot selector', 'Interpreter', 'latex')
            xlabel(app.KnotsSelectorUIAxes, 'time', 'Interpreter', 'latex')
            app.KnotsSelectorUIAxes.ButtonDownFcn = createCallbackFcn(app, @KnotsSelectorButtonDown, true);
            app.PenalizedIntervalUIAxes = uiaxes(app.LeftPanel);
            title(app.PenalizedIntervalUIAxes, 'Penalized interval selector', 'Interpreter', 'latex')
            xlabel(app.PenalizedIntervalUIAxes, 'time', 'Interpreter', 'latex')
            app.PenalizedIntervalUIAxes.YTick = [];
            app.PenalizedIntervalUIAxes.ButtonDownFcn = createCallbackFcn(app, @PenalizedIntervalButtonDown, true);
            app.SmoothButton = uibutton(app.LeftPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.LiveCheckBox = uicheckbox(app.LeftPanel);
            app.LiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveValueChanged, true);
            app.LiveCheckBox.Text = 'Live';
            app.LiveCheckBox.Value = true;
            app.OverridewithequidistantknotsSliderLabel = uilabel(app.LeftPanel);
            app.OverridewithequidistantknotsSliderLabel.Text = {'Override with'; 'equidistant knots'};
            app.KnotsSlider = uislider(app.LeftPanel);
            app.KnotsSlider.Limits = [0 40];
            app.KnotsSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.KnotsSlider.MajorTickLabels = {'0', ' ', '10', ' ', '20', ' ', '30', ' ', '40'};
            app.KnotsSlider.ValueChangedFcn = createCallbackFcn(app, @KnotsSliderValueChanged, true);
            app.KnotsSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.KnotsSlider.Value = 20;
            app.FinishButton = uibutton(app.LeftPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.logLabel = uilabel(app.LeftPanel);
            app.logLabel.Text = 'λ correction multiplier';
            app.MultiplierSlider = uislider(app.LeftPanel);
            app.MultiplierSlider.Limits = [-4 4];
            app.MultiplierSlider.MajorTicks = [-4 -2 0 2 4];
            app.MultiplierSlider.MajorTickLabels = {'0.0001', '0.01', '1', '100', '10000'};
            app.MultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @MultiplierValueChanged, true);
            app.MultiplierSlider.MinorTicks = [-4 -3 -2 -1 0 1 2 3 4];
            app.CellstodisplaySliderLabel = uilabel(app.LeftPanel);
            app.CellstodisplaySliderLabel.Text = {'#Cells to'; 'display'};
            app.CellsSlider = uislider(app.LeftPanel);
            app.CellsSlider.ValueChangedFcn = createCallbackFcn(app, @CellsSliderValueChanged, true);
            app.StateDropDownLabel = uilabel(app.LeftPanel);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.LeftPanel);
            app.StateDropDown.Items = {'All'};
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateValueChanged, true);
            app.StateDropDown.Value = 'All';
            app.AutomaticButton = uibutton(app.LeftPanel, 'push');
            app.AutomaticButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticButtonPushed, true);
            app.AutomaticButton.Text = 'Override with automatic knot placement';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smoothing_new(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end